classdef App_RN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        relatorio                      matlab.ui.container.Menu
        sair                           matlab.ui.container.Menu
        autorMenu                      matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        NomedaRedeNeuronalaCarregar    matlab.ui.control.EditField
        NomedaRedeNeuronalacarregarEditFieldLabel  matlab.ui.control.Label
        NomedaRedeNeuronalaGuardar     matlab.ui.control.EditField
        NomedaRedeNeuronalaguardarEditFieldLabel  matlab.ui.control.Label
        GraverRede                     matlab.ui.control.Button
        CarregarRede                   matlab.ui.control.Button
        NumeroDeNeuronios              matlab.ui.control.NumericEditField
        NmerodeneurniosEditFieldLabel  matlab.ui.control.Label
        FuncaoDeAtivacao4              matlab.ui.control.DropDown
        FunodeativaoDropDown_3Label_2  matlab.ui.control.Label
        FuncaoDeAtivacao3              matlab.ui.control.DropDown
        FunodeativaoDropDown_3Label    matlab.ui.control.Label
        FuncaoDeAtivacao2              matlab.ui.control.DropDown
        FunodeativaoDropDown_2Label    matlab.ui.control.Label
        NumeroDeCamadasEscondidas      matlab.ui.control.DropDown
        NmerodecamadasescondidasDropDownLabel  matlab.ui.control.Label
        FeedbackRede                   matlab.ui.control.TextArea
        RedeTreinadacomTextAreaLabel   matlab.ui.control.Label
        PrecisaoTeste                  matlab.ui.control.EditField
        PrecisoTesteEditFieldLabel     matlab.ui.control.Label
        PrecisaoTotal                  matlab.ui.control.EditField
        PrecisoTotalEditFieldLabel     matlab.ui.control.Label
        PastaatreinarButtonGroup       matlab.ui.container.ButtonGroup
        TodasAsPastas                  matlab.ui.control.RadioButton
        Train                          matlab.ui.control.RadioButton
        Test                           matlab.ui.control.RadioButton
        Start                          matlab.ui.control.RadioButton
        TreinarRede                    matlab.ui.control.Button
        TesteRatio                     matlab.ui.control.NumericEditField
        TesteRatioEditFieldLabel       matlab.ui.control.Label
        ValRatio                       matlab.ui.control.NumericEditField
        ValRatioEditFieldLabel         matlab.ui.control.Label
        TrainRatio                     matlab.ui.control.NumericEditField
        TrainRatioEditFieldLabel       matlab.ui.control.Label
        Topologia                      matlab.ui.control.EditField
        TopologiaEditFieldLabel        matlab.ui.control.Label
        FuncaoDeTreino                 matlab.ui.control.DropDown
        FunodetreinoDropDownLabel      matlab.ui.control.Label
        FuncaoDeAtivacao1              matlab.ui.control.DropDown
        FunodeativaoDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        net_atual %variável que tem o net atual
        tr_atual % tr atual
        accTot_atual % Precisao total
        accTes_atual % Precisao teste
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           switch app.NumeroDeCamadasEscondidas.Value
                case '1'
                    app.FuncaoDeAtivacao1.Enable = true;
                    app.FuncaoDeAtivacao2.Enable = true;
                    app.FuncaoDeAtivacao3.Enable = false;
                    app.FuncaoDeAtivacao4.Enable = false;
           end
        end

        % Button pushed function: TreinarRede
        function TreinarRedePushed(app, event)
            escolhabgMetodos = app.PastaatreinarButtonGroup.SelectedObject;
            metodoSelecionado = find([app.Start,...
                                     app.Test,...
                                     app.Train,...
                                     app.TodasAsPastas,...
                                     ]==escolhabgMetodos);
           
            switch metodoSelecionado
                    case 1  
                         [app.net_atual, app.tr_atual, app.accTot_atual] = App_redes_neuronais_a(app.FuncaoDeTreino.Value);
                         app.PrecisaoTotal.Value = num2str(app.accTot_atual, '%.2f %%');                         
                         app.FeedbackRede.FontColor = 'g';
                         app.FeedbackRede.Value = 'Sucesso';
                    case 2
                         [app.accTot_atual] = App_redes_neuronais_c(app.NomedaRedeNeuronalaCarregar.Value);
                         app.PrecisaoTotal.Value = num2str(app.accTot_atual, '%.2f %%');
                         app.FeedbackRede.FontColor = 'g';
                         app.FeedbackRede.Value = 'Sucesso';

                    case 3
                         [app.net_atual, app.tr_atual, app.accTot_atual, app.accTes_atual] = App_redes_neuronais_b(app.FuncaoDeTreino.Value, app.TrainRatio.Value, app.TesteRatio.Value, app.ValRatio.Value, app.NumeroDeNeuronios.Value, app.NumeroDeCamadasEscondidas.Value, app.FuncaoDeAtivacao1.Value, app.FuncaoDeAtivacao2.Value, app.FuncaoDeAtivacao3.Value, app.FuncaoDeAtivacao4.Value);
                         app.PrecisaoTotal.Value = num2str(app.accTot_atual, '%.2f %%'); 
                         app.PrecisaoTeste.Value = num2str(app.accTes_atual, '%.2f %%'); 
                         app.FeedbackRede.FontColor = 'g';
                         app.FeedbackRede.Value = 'Sucesso';
                        
                    case 4
                         [app.net_atual, app.tr_atual, app.accTot_atual, app.accTes_atual] = App_redes_neuronais_c_todas_as_pastas(app.FuncaoDeTreino.Value, app.TrainRatio.Value, app.TesteRatio.Value, app.ValRatio.Value, app.NumeroDeNeuronios.Value, app.NumeroDeCamadasEscondidas.Value, app.FuncaoDeAtivacao1.Value, app.FuncaoDeAtivacao2.Value, app.FuncaoDeAtivacao3.Value, app.FuncaoDeAtivacao4.Value);
                         app.PrecisaoTotal.Value = num2str(app.accTot_atual, '%.2f %%'); 
                         app.PrecisaoTeste.Value = num2str(app.accTes_atual, '%.2f %%'); 
                         app.FeedbackRede.FontColor = 'g';
                         app.FeedbackRede.Value = 'Sucesso';
                         
            end
            
        end

        % Value changed function: NumeroDeCamadasEscondidas
        function NumeroDeCamadasEscondidasValueChanged(app, event)
            value = app.NumeroDeCamadasEscondidas.Value;
            
            switch value
                case '1'
                    app.FuncaoDeAtivacao1.Enable = true;
                    app.FuncaoDeAtivacao2.Enable = true;
                    app.FuncaoDeAtivacao3.Enable = false;
                    app.FuncaoDeAtivacao4.Enable = false;
                case '2'
                    app.FuncaoDeAtivacao1.Enable = true;
                    app.FuncaoDeAtivacao2.Enable = true;
                    app.FuncaoDeAtivacao3.Enable = true;
                    app.FuncaoDeAtivacao4.Enable = false;
                case '3'
                    app.FuncaoDeAtivacao1.Enable = true;
                    app.FuncaoDeAtivacao2.Enable = true;
                    app.FuncaoDeAtivacao3.Enable = true;
                    app.FuncaoDeAtivacao4.Enable = true;
            end
        end

        % Button pushed function: GraverRede
        function GraverRedeButtonPushed(app, event)
             if isempty(app.NomedaRedeNeuronalaGuardar.Value)
                  errordlg('Digite o nome do Ficheiro','ERRO','modal')
             else
                Save_Rede_Neuronal(app.NomedaRedeNeuronalaGuardar.Value, app.net_atual, app.tr_atual);
             end
        end

        % Button pushed function: CarregarRede
        function CarregarRedeButtonPushed(app, event)
            if isempty(app.NomedaRedeNeuronalaCarregar.Value)
                  errordlg('Digite o nome do Ficheiro','ERRO','modal')
             else
                [app.net_atual, app.tr_atual] = Load_Rede_Neuronal(app.NomedaRedeNeuronalaCarregar.Value);
             end
        end

        % Menu selected function: autorMenu
        function autorMenuSelected(app, event)
            appAutores
        end

        % Menu selected function: sair
        function sairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: relatorio
        function relatorioMenuSelected(app, event)
            open RelatorioCR_TiagoQuintas2019128044_FranciscoReis2019143035.pdf
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.relatorio = uimenu(app.Menu);
            app.relatorio.MenuSelectedFcn = createCallbackFcn(app, @relatorioMenuSelected, true);
            app.relatorio.Text = 'Relatório';
            app.sair = uimenu(app.Menu);
            app.sair.MenuSelectedFcn = createCallbackFcn(app, @sairMenuSelected, true);
            app.sair.Text = 'Sair';
            app.autorMenu = uimenu(app.UIFigure);
            app.autorMenu.MenuSelectedFcn = createCallbackFcn(app, @autorMenuSelected, true);
            app.autorMenu.Text = 'Autores';
            app.Panel = uipanel(app.UIFigure);
            app.FunodeativaoDropDownLabel = uilabel(app.Panel);
            app.FunodeativaoDropDownLabel.Text = 'Função de ativação';
            app.FuncaoDeAtivacao1 = uidropdown(app.Panel);
            app.FuncaoDeAtivacao1.Items = {'tansig', 'radbasn', 'netinv', 'satlins', 'hardlims', 'logsig', 'purelin'};
            app.FuncaoDeAtivacao1.Value = 'tansig';
            app.FunodetreinoDropDownLabel = uilabel(app.Panel);
            app.FunodetreinoDropDownLabel.Text = 'Função de treino';
            app.FuncaoDeTreino = uidropdown(app.Panel);
            app.FuncaoDeTreino.Items = {'trainscg', 'trainlm', 'trainbr', 'trainbfg', 'trainrp', 'traincgb', 'traincgf', 'traincgp', 'trainoss', 'traingdx', 'traingdm', 'traingd'};
            app.FuncaoDeTreino.Value = 'trainscg';
            app.TopologiaEditFieldLabel = uilabel(app.Panel);
            app.TopologiaEditFieldLabel.Text = 'Topologia';
            app.Topologia = uieditfield(app.Panel, 'text');
            app.Topologia.Editable = 'off';
            app.Topologia.Value = 'feedforwardnet';
            app.TrainRatioEditFieldLabel = uilabel(app.Panel);
            app.TrainRatioEditFieldLabel.Text = 'Train Ratio';
            app.TrainRatio = uieditfield(app.Panel, 'numeric');
            app.TrainRatio.Value = 0.7;
            app.ValRatioEditFieldLabel = uilabel(app.Panel);
            app.ValRatioEditFieldLabel.Text = 'Val Ratio';
            app.ValRatio = uieditfield(app.Panel, 'numeric');
            app.ValRatio.Value = 0.15;
            app.TesteRatioEditFieldLabel = uilabel(app.Panel);
            app.TesteRatioEditFieldLabel.Text = 'Teste Ratio';
            app.TesteRatio = uieditfield(app.Panel, 'numeric');
            app.TesteRatio.Value = 0.15;
            app.TreinarRede = uibutton(app.Panel, 'push');
            app.TreinarRede.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedePushed, true);
            app.TreinarRede.Text = 'Treinar Rede';
            app.PastaatreinarButtonGroup = uibuttongroup(app.Panel);
            app.PastaatreinarButtonGroup.Title = 'Pasta a treinar';
            app.Start = uiradiobutton(app.PastaatreinarButtonGroup);
            app.Start.Text = {'Start'; ''};
            app.Start.Value = true;
            app.Test = uiradiobutton(app.PastaatreinarButtonGroup);
            app.Test.Text = 'Test';
            app.Train = uiradiobutton(app.PastaatreinarButtonGroup);
            app.Train.Text = 'Train';
            app.TodasAsPastas = uiradiobutton(app.PastaatreinarButtonGroup);
            app.TodasAsPastas.Text = 'Todas as pastas';
            app.PrecisoTotalEditFieldLabel = uilabel(app.Panel);
            app.PrecisoTotalEditFieldLabel.Text = 'Precisão Total';
            app.PrecisaoTotal = uieditfield(app.Panel, 'text');
            app.PrecisaoTotal.Editable = 'off';
            app.PrecisoTesteEditFieldLabel = uilabel(app.Panel);
            app.PrecisoTesteEditFieldLabel.Text = 'Precisão Teste';
            app.PrecisaoTeste = uieditfield(app.Panel, 'text');
            app.PrecisaoTeste.Editable = 'off';
            app.RedeTreinadacomTextAreaLabel = uilabel(app.Panel);
            app.RedeTreinadacomTextAreaLabel.Text = 'Rede Treinada com:';
            app.FeedbackRede = uitextarea(app.Panel);
            app.FeedbackRede.Editable = 'off';
            app.NmerodecamadasescondidasDropDownLabel = uilabel(app.Panel);
            app.NmerodecamadasescondidasDropDownLabel.Text = 'Número de camadas escondidas';
            app.NumeroDeCamadasEscondidas = uidropdown(app.Panel);
            app.NumeroDeCamadasEscondidas.Items = {'1', '2', '3'};
            app.NumeroDeCamadasEscondidas.ValueChangedFcn = createCallbackFcn(app, @NumeroDeCamadasEscondidasValueChanged, true);
            app.NumeroDeCamadasEscondidas.Value = '1';
            app.FunodeativaoDropDown_2Label = uilabel(app.Panel);
            app.FunodeativaoDropDown_2Label.Text = 'Função de ativação';
            app.FuncaoDeAtivacao2 = uidropdown(app.Panel);
            app.FuncaoDeAtivacao2.Items = {'radbasn', 'netinv', 'satlins', 'purelin', 'hardlims', 'tansig', 'logsig'};
            app.FuncaoDeAtivacao2.Value = 'radbasn';
            app.FunodeativaoDropDown_3Label = uilabel(app.Panel);
            app.FunodeativaoDropDown_3Label.Text = 'Função de ativação';
            app.FuncaoDeAtivacao3 = uidropdown(app.Panel);
            app.FuncaoDeAtivacao3.Items = {'logsig', 'purelin', 'radbasn', 'netinv', 'satlins', 'hardlims', 'tansig'};
            app.FuncaoDeAtivacao3.Value = 'logsig';
            app.FunodeativaoDropDown_3Label_2 = uilabel(app.Panel);
            app.FunodeativaoDropDown_3Label_2.Text = 'Função de ativação';
            app.FuncaoDeAtivacao4 = uidropdown(app.Panel);
            app.FuncaoDeAtivacao4.Items = {'satlins', 'hardlims', 'logsig', 'netinv', 'purelin', 'tansig', 'radbasn'};
            app.FuncaoDeAtivacao4.Value = 'satlins';
            app.NmerodeneurniosEditFieldLabel = uilabel(app.Panel);
            app.NmerodeneurniosEditFieldLabel.Text = 'Número de neurónios';
            app.NumeroDeNeuronios = uieditfield(app.Panel, 'numeric');
            app.NumeroDeNeuronios.Limits = [1 50];
            app.NumeroDeNeuronios.Value = 50;
            app.CarregarRede = uibutton(app.Panel, 'push');
            app.CarregarRede.ButtonPushedFcn = createCallbackFcn(app, @CarregarRedeButtonPushed, true);
            app.CarregarRede.Text = 'Carregar Rede';
            app.GraverRede = uibutton(app.Panel, 'push');
            app.GraverRede.ButtonPushedFcn = createCallbackFcn(app, @GraverRedeButtonPushed, true);
            app.GraverRede.Text = 'Gravar Rede';
            app.NomedaRedeNeuronalaguardarEditFieldLabel = uilabel(app.Panel);
            app.NomedaRedeNeuronalaguardarEditFieldLabel.Text = 'Nome da Rede Neuronal a guardar:';
            app.NomedaRedeNeuronalaGuardar = uieditfield(app.Panel, 'text');
            app.NomedaRedeNeuronalacarregarEditFieldLabel = uilabel(app.Panel);
            app.NomedaRedeNeuronalacarregarEditFieldLabel.Text = 'Nome da Rede Neuronal a carregar:';
            app.NomedaRedeNeuronalaCarregar = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_RN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end